classdef Projectile_Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Versionlabel                matlab.ui.control.Label
        ProjectileSimulatorLabel    matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        simulator                   matlab.ui.container.Tab
        simlayout                   matlab.ui.container.GridLayout
        LocationTrackerPanel        matlab.ui.container.Panel
        locate                      matlab.ui.control.Button
        secLabel                    matlab.ui.control.Label
        locationdisp                matlab.ui.control.TextArea
        timelocate                  matlab.ui.control.NumericEditField
        EnterthetimetocheckthelocationoftheobjectEditFieldLabel  matlab.ui.control.Label
        OutputPanel                 matlab.ui.container.Panel
        peak                        matlab.ui.control.EditField
        PeakLocationEditFieldLabel  matlab.ui.control.Label
        mLabel_4                    matlab.ui.control.Label
        timeofflight                matlab.ui.control.NumericEditField
        TimeofflightLabel           matlab.ui.control.Label
        secLabel_2                  matlab.ui.control.Label
        msLabel                     matlab.ui.control.Label
        angle                       matlab.ui.control.NumericEditField
        AngleofLaunchLabel          matlab.ui.control.Label
        radsLabel                   matlab.ui.control.Label
        velocity                    matlab.ui.control.NumericEditField
        VelocityLabel               matlab.ui.control.Label
        mLabel_2                    matlab.ui.control.Label
        MH                          matlab.ui.control.NumericEditField
        MaxHieghtLabel              matlab.ui.control.Label
        InputPanel                  matlab.ui.container.Panel
        SaveButton                  matlab.ui.control.Button
        resetall                    matlab.ui.control.Button
        simulate                    matlab.ui.control.Button
        RTSSSwitchLabel             matlab.ui.control.Label
        RTSSSwitch                  matlab.ui.control.Switch
        mLabel_3                    matlab.ui.control.Label
        Distance                    matlab.ui.control.NumericEditField
        RangeLabel                  matlab.ui.control.Label
        IntialHeightLabel           matlab.ui.control.Label
        Hieght                      matlab.ui.control.NumericEditField
        AccelerationLabel           matlab.ui.control.Label
        mLabel                      matlab.ui.control.Label
        acceleration                matlab.ui.control.DropDown
        RTSSPanel                   matlab.ui.container.Panel
        TimeElapsedLabel            matlab.ui.control.Label
        TimeElapsedValue            matlab.ui.control.Label
        TimeRemainingLabel          matlab.ui.control.Label
        TimeRemainingValue          matlab.ui.control.Label
        CurrentHeightLabel          matlab.ui.control.Label
        CurrentHeightValue          matlab.ui.control.Label
        DistanceCoveredLabel        matlab.ui.control.Label
        DistanceCoveredValue        matlab.ui.control.Label
        DistanceRemainingLabel      matlab.ui.control.Label
        DistanceRemainingValue      matlab.ui.control.Label
        ReplayButton                matlab.ui.control.Button
        FastRewindButton            matlab.ui.control.Button
        Skipback                    matlab.ui.control.Button
        PlayPauseButton             matlab.ui.control.StateButton
        Skipforward                 matlab.ui.control.Button
        FastForwardButton           matlab.ui.control.Button
        InputPlaybackSpeed          matlab.ui.control.Spinner
        MainGraph                   matlab.ui.control.UIAxes
        GraphsTab                   matlab.ui.container.Tab
        GridLayout                  matlab.ui.container.GridLayout
        Jerk                        matlab.ui.control.NumericEditField
        MaxDisplacementLabel_10     matlab.ui.control.Label
        MinAcceleration             matlab.ui.control.NumericEditField
        MaxDisplacementLabel_9      matlab.ui.control.Label
        MaxAcceleration             matlab.ui.control.NumericEditField
        MaxDisplacementLabel_8      matlab.ui.control.Label
        AvgAcceleration             matlab.ui.control.NumericEditField
        MaxDisplacementLabel_7      matlab.ui.control.Label
        MinVelocity                 matlab.ui.control.NumericEditField
        MaxDisplacementLabel_5      matlab.ui.control.Label
        MaxVelocity                 matlab.ui.control.NumericEditField
        MaxDisplacementLabel_4      matlab.ui.control.Label
        AvgVelocity                 matlab.ui.control.NumericEditField
        MaxDisplacementLabel_3      matlab.ui.control.Label
        MinDisplacement             matlab.ui.control.NumericEditField
        MaxDisplacementLabel_2      matlab.ui.control.Label
        MaxDisplacement             matlab.ui.control.NumericEditField
        MaxDisplacementLabel        matlab.ui.control.Label
        DisplacementTimeGraph       matlab.ui.control.UIAxes
        VelocityTimeGraph           matlab.ui.control.UIAxes
        AccelerationTimeGraph       matlab.ui.control.UIAxes
        HistoryTab                  matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        TabGroup2                   matlab.ui.container.TabGroup
        MainTab                     matlab.ui.container.Tab
        ClearAllButton              matlab.ui.control.Button
        DataDropDown                matlab.ui.control.DropDown
        DataDropDownLabel           matlab.ui.control.Label
        ImportButton                matlab.ui.control.Button
        SimulationH                 matlab.ui.control.Table
        Historygraph                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        X % Main - X Values
        Y % Main - Y Values
        T % Main Time
        TMAX % Maximum Time

        sAni = true % Should the program animate
        isAni = true % Is the program animating
        isPlay = true % Is the program playing the animation
        isUpdat = true % Is the program updating
        cTime = 0 % Start Time
        pSpeed = 1 % Playback speed
        timerr = timer('ExecutionMode','fixedRate', ...
                'Period', 0.1, ...
                'TimerFcn', @(~,~) updateRTSS(app));

        hisname = [] % array of data for importing in graph (History Tab)
        playIcon = 'Play.png' % Play Icon
        pauseIcon = 'Pause.png' % Pause Icon

        new_tab = []
    end

    methods (Access = private)


        % Simulation Tab

        function [V,alpha,g,t,H] = input(app) % This is the input function of the program
            x = app.Distance.Value; % Velocity
            H = app.Hieght.Value + 1;
            a = app.acceleration.Value; % Acceleration ie. Gravity
            y_b = 3;    % Height of the basket in meters
            d = 6;      % Distance from building to basket in meters
            D = x + d;  % Total horizontal distance from thrower to basket

            if a == "Earth"
                g = 9.81; % Earth Gravity
            elseif a == "Moon"
                g = 1.62; % Moon Gravity
            elseif a == "Mars"
                g = 3.71; % Mars Gravity
            end

            function F = equations(vars)
                theta = vars(1);
                v0 = vars(2);

                % Equation for the vertical position at the basket
                eq1 = y_b - (D * tan(theta) - (1/2) * g * (D / (v0 * cos(theta)))^2);

                % Equation for the height at the building
                eq2 = H - (x * tan(theta) - (1/2) * g * (x / (v0 * cos(theta)))^2);

                % Return the system of equations
                F = [eq1; eq2];
            end

            % Initial guesses for theta and v0
            initial_guess = [0.000001 * pi / 180, 0.000001];  % Initial guess: 0.000001 degrees and 0.000001 m/s

            % Solve the system of equations using fsolve
            options = optimoptions('fsolve', 'MaxFunEvals', 1000000000000000000000, 'MaxIterations', 1000000000000000000000);
            solution = fsolve(@equations, initial_guess, options);

            % Extract the solutions
            alpha = solution(1);
            theta_solution_deg = solution(1)*180/pi();
            V    = solution(2);
            t = D / (V * cos(alpha));

            app.velocity.Value = V;
            app.angle.Value = alpha;
            app.timeofflight.Value = t;
        end


        function [V,alpha,g,tofflight] = sim(app) % runs the simulation function
            [V,alpha,g,t] = input(app);


            app.velocity.Value = V ; % Displays Velocity
            app.angle.Value = alpha; % Displays Angle

            vy = V * sin(alpha);
            vx = V * cos(alpha);

            tofflight  =t ; % Calculates the time of flight
            app.timeofflight.Value = tofflight;
            app.TMAX = tofflight;


            t_max_height = V * sin(alpha) / g;
            y_max_height = V * sin(alpha) * t_max_height - 0.5 * g * t_max_height^2; % Max Hieght Y - Coordinates
            x_max_height = V * cos(alpha) * t_max_height - 0.5 * g * t_max_height^2; % Max Hieght X - Coordinates
            app.peak.Value = ['(',num2str(x_max_height),',',num2str(y_max_height),')'];
            app.MH.Value = y_max_height; % Displays Max Hieght

            app.locate.Enable ="on"; % Enables the locate button
            app.timelocate.Editable = "on"; % Enables the Time field
            app.timelocate.Enable = "on"; % Enables the Time field
          
            app.secLabel.Enable ="on";
            t = linspace(0, t, 100); % Time array
            x = V * cos(alpha) * t; % X - Axis
            y = V * sin(alpha) * t - 0.5 * g * t.^2; % Y - Axis

            y(y < 0) = 0;
            app.X = x;
            app.Y = y;
            app.T = t;
            app.TMAX = tofflight;

            startRTSS(app);
            app.SaveButton.Enable = "on";

        end

        function plotgraph(app) % Plots the static Graph

            [V,alpha,g,t] = input(app);

            t = linspace(0, t, 100); % Time array
            x = V * cos(alpha) * t; % X - Axis
            y = V * sin(alpha) * t - 0.5 * g * t.^2; % Y - Axis

            plot(app.MainGraph,x,y)
        end

        function locatew(app) % The function locates the projectile in x,y coordinaties in a given time period

            [V,alpha,g] = input(app);
            t_check = app.timelocate.Value; % Time field
            x_check = V * cos(alpha) * t_check; % X - Location
            y_check = V * sin(alpha) * t_check - 0.5 * g * t_check^2; % Y - Location

            if t_check >= app.TMAX % if input time is greater or equal to time of flight it corrects the dislay coordnates
                x_check = V * cos(alpha) * app.TMAX;
                app.locationdisp.Value= ['At time ' num2str(t_check,4) ' s, the object is at position (' num2str(x_check) ', 0)'];  % Displays time, (x,y) coordinates in locationdisp if time input is greater or equal to time of flight
            else
                app.locationdisp.Value= ['At time ' num2str(round(t_check)) ' s, the object is at position (' num2str(x_check) ', ' num2str(y_check) ')'];  % Displays time, (x,y) coordinates in locationdisp
            end

        end

        function updateRTSS(app) % This functions Updates the Real time animation of the RTSS
            if app.sAni
                if app.isAni && all(app.cTime >= 0) && all(app.cTime <= (app.TMAX + 1))
                    index = find(app.T <= app.cTime, 1, 'last');

                    if ~isempty(index)
                        x = app.X(index);
                        y = app.Y(index);
                        updateRTSSv(app, x, y, app.T(index));

                        plot(app.MainGraph, x, y, 'o', 'MarkerFaceColor', 'red');
                        hold(app.MainGraph, 'on');
                        plot(app.MainGraph, app.X(1:index), app.Y(1:index), '-');

                        xlim(app.MainGraph, [min(app.X), max(app.X)]);
                        ylim(app.MainGraph, [min(app.Y), max(app.Y)]);

                        drawnow;
                        hold(app.MainGraph, 'off');

                        if app.isUpdat
                            app.cTime = app.cTime + (app.pSpeed / 4);
                        else
                            app.isUpdat = true;
                        end
                    end
                else
                    stop(app.timerr);
                    app.isAni = false;
                end
            else
                plot(app.MainGraph, app.X(end), app.Y(end), 'o', 'MarkerFaceColor', 'red');
                hold(app.MainGraph, 'on');
                plot(app.MainGraph, app.X, app.Y, '-');

                xlim(app.MainGraph, [min(app.X), max(app.X)]);
                ylim(app.MainGraph, [min(app.Y), max(app.Y)]);
                hold(app.MainGraph, 'off');
            end
        end

        function pauseRTSS(app) % This function pauses the animation
            app.isPlay = false;
            app.isUpdat = false;
            stop(app.timerr)
            app.PlayPauseButton.Icon = app.playIcon;
            app.timerr.running
        end

        function resumeRTSS(app) % This function resumes the animation
            app.isPlay = true;
            start(app.timerr);
            app.PlayPauseButton.Icon = app.pauseIcon;
        end

        function startRTSS(app) % This function starts the RTSS
            app.cTime = 0;
            app.isAni = true;
            app.isPlay = true;
            app.PlayPauseButton.Icon = app.pauseIcon;
            app.timerr = timer('ExecutionMode','fixedRate', ...
                'Period', 0.1, ...
                'TimerFcn', @(~,~) updateRTSS(app));
            start(app.timerr);
        end

        function updateRTSSv(app, x, y, t) % This function updates the values of the RTSS
            distanceRemaining = max(app.X) - x;
            timeRemaining = app.TMAX - t;

            app.CurrentHeightValue.Text = sprintf('%.2f m', y);
            app.DistanceCoveredValue.Text = sprintf('%.2f m', x);
            app.DistanceRemainingValue.Text = sprintf('%.2f m', distanceRemaining);
            % app.CurrentVelocityValue.Text = sprintf('%.2f m/s', currentVelocity);
            app.TimeElapsedValue.Text = sprintf('%.2f s', t);
            app.TimeRemainingValue.Text = sprintf('%.2f s', timeRemaining);
        end

        function tweakRTSS(app, pspeed) % This function tweaks the speed of the animation
            if app.isAni
                pauseRTSS(app);
                app.cTime = app.cTime + (app.pSpeed / pspeed);

                if app.cTime < 0
                    app.cTime = 0;
                end

                updateRTSS(app);
            end
        end


        function reset(app) % This function resets the entire program and disables the RTSS panel and Location panel
            app.velocity.Value = 0;
            app.angle.Value = 0;
            app.Hieght.Value = 0;
            sim(app);
            plotdisp(app);
            plotvel(app);
            plotacc(app);
            plotgraph(app);
            app.RTSSSwitch.Value = "Off";
            app.peak.Value = "(x,y)";
            app.locate.Enable ="off";
            app.timelocate.Editable = "off";
            app.RTSSPanel.Enable = "off";
            app.ReplayButton.Enable = "off";
            app.FastForwardButton.Enable = "off";
            app.FastRewindButton.Enable= "off";
            app.ReplayButton.Enable= "off";
            app.Skipback.Enable= "off";
            app.PlayPauseButton.Enable = "off";
            app.Skipforward.Enable= "off";
            app.InputPlaybackSpeed.Enable= "off";

        end

        function rtssenable(app) % This function enables or disables the RTSS
            rs = app.RTSSSwitch.Value;
            if strcmp(rs,'On') % Check the RTSS switch if it is ON and enables the RTSS panel
                app.RTSSPanel.Enable = "on";
                app.ReplayButton.Enable = "on";
                app.FastForwardButton.Enable = "on";
                app.FastRewindButton.Enable= "on";
                app.ReplayButton.Enable= "on";
                app.Skipback.Enable= "on";
                app.PlayPauseButton.Enable = "on";
                app.Skipforward.Enable= "on";
                app.InputPlaybackSpeed.Enable= "on";
                app.sAni = true;
            else % If off then disables the panel along with its buttons
                app.sAni = false;
                app.plotgraph;
                app.RTSSPanel.Enable = "off";
                app.ReplayButton.Enable = "off";
                app.FastForwardButton.Enable = "off";
                app.FastRewindButton.Enable= "off";
                app.ReplayButton.Enable= "off";
                app.Skipback.Enable= "off";
                app.PlayPauseButton.Enable = "off";
                app.Skipforward.Enable= "off";
                app.InputPlaybackSpeed.Enable= "off";

            end
        end


        function plotdisp(app) % This function plots displacement in graphs tab
            [V, alpha, g, H] = input(app);
            max_x = 2 * V * sin(alpha) / g; % Max time
            x = linspace(0, max_x, 10000); % Time array
            y = H + V * sin(alpha) * x - 0.5 * g * x.^2; % Displacement based on time
            plot(app.DisplacementTimeGraph, x, y) % Plot y vs x (displacement vs time)
            app.MaxDisplacement.Value = max(y); % Max Displacement
            app.AvgVelocity.Value = V * cos(alpha); % Average Velocity
        end

        function plotvel(app) % This fucntions plots velocity in the graphs tab
            [V, alpha, g] = input(app);
            x = linspace(0, 2 * V * sin(alpha) / g, 10000); % Time array
            y = linspace(0, V, 10000); % Velocity array
            plot(app.VelocityTimeGraph, x, y)
            app.MaxVelocity.Value = V; % Max velocity
            app.AvgAcceleration.Value = -g; % Average acceleration
        end


        function plotacc(app) % This function plots acceleration in the graphs tab
            [V, alpha, g] = input(app);
            x = linspace(0, 2 * V * sin(alpha) / g, 10000); % Time array
            y = linspace(-g, -g, 10000); % Acceleration array
            plot(app.AccelerationTimeGraph, x, y)
            app.MaxAcceleration.Value = -g; % Max Acceleration
            app.Jerk.Value = 0; % Jerk = 0 since dy/dt(constant) = 0
        end

        function saveval(app,s)
            [V,alpha,g,hieght] = input(app);
            d = [s,V,hieght,alpha,g];
            sh = app.SimulationH.Data;
            app.SimulationH.Data = [sh;d];
        end


% History Tab


        function simh(app)

            db = app.SimulationH.Data;
            h = height(db);
            disp(db)
            disp(h)
            app.DataDropDown.Items = {};
            app.DataDropDown.ItemsData ={};
            for i = 1:h
                name = db(i,1);
                ddrop = app.DataDropDown.Items;
                app.DataDropDown.Items = [ddrop name];
            end
        end

        function importf(app)
            db = app.SimulationH.Data;
            name = app.DataDropDown.Value;
            index = app.DataDropDown.ValueIndex;
            disp(index)
            val = db(index,:);
            V= cell2mat(val(1,2));
            alpha = cell2mat(val(1,4));
            g= cell2mat(val(1,5));
            [tofh,rangeh,maxh,peakvalue] = calch(app,V,alpha,g);

            disp(val)
            disp([tofh,rangeh,maxh])

            newtab(app,name,tofh,rangeh,maxh,peakvalue)
        end

        function newtab(app,name,tofh,rangeh,maxh,peakvalue)

            tabname = uitab(app.TabGroup2,"Title",name);
            app.new_tab = [app.new_tab, tabname];
            % Create TimeofflightLabel_2
            TimeofflightLabel_tab = uilabel(tabname);
            TimeofflightLabel_tab.Position = [8 278 78 22];
            TimeofflightLabel_tab.Text = 'Time of flight:';
            Timeofflight = uieditfield(tabname, 'text');
            Timeofflight.Editable = 'off';
            Timeofflight.HorizontalAlignment = 'center';
            Timeofflight.Value = num2str(tofh);
            Timeofflight.Position = [92 278 100 22];

            % Create MaxHieghtLabel_2
            MaxHieghtLabel_tab = uilabel(tabname);
            MaxHieghtLabel_tab.Position = [8 215 70 22];
            MaxHieghtLabel_tab.Text = 'Max Hieght:';

            % Create MH_2
            MH_tab = uieditfield(tabname, 'numeric');
            MH_tab.Editable = 'off';
            MH_tab.HorizontalAlignment = 'center';
            MH_tab.Position = [92 215 101 22];
            MH_tab.Value = maxh ;

            % Create RangeLabel_2
            RangeLabel_tab = uilabel(tabname);
            RangeLabel_tab.Position = [10 183 43 22];
            RangeLabel_tab.Text = 'Range:';

            % Create range_2
            range_tab = uieditfield(tabname, 'numeric');
            range_tab.Editable = 'off';
            range_tab.HorizontalAlignment = 'center';
            range_tab.Position = [92 183 100 22];
            range_tab.Value = rangeh;

            % Create PeakLocationEditFieldLabel_2
            PeakLocationEditFieldLabel_tab = uilabel(tabname);
            PeakLocationEditFieldLabel_tab.Position = [8 247 85 22];
            PeakLocationEditFieldLabel_tab.Text = 'Peak Location:';

            % Create peak_2
            peak_tab = uieditfield(tabname, 'text');
            peak_tab.Editable = 'off';
            peak_tab.HorizontalAlignment = 'center';
            peak_tab.Placeholder = '(x,y)';
            peak_tab.Position = [93 247 100 22];
            peak_tab.Value = peakvalue;


        end


        function [tofh,rangeh,maxhis,peakvalue] = calch(~,V,alpha,g)

            tofh = (2*V*sin(alpha)/g);
            rangeh = V * cos(alpha) * tofh;
            t_max_height = V * sin(alpha) / g;
            maxhis = V * sin(alpha) * t_max_height - 0.5 * g * t_max_height^2;
            x_max_height = V * cos(alpha) * t_max_height - 0.5 * g * t_max_height^2; % Max Hieght X - Coordinates
            peakvalue = ['(',num2str(x_max_height),',',num2str(maxhis),')'];
            disp(tofh)
            disp(rangeh)
        end


        function plothis(app)

            index = app.DataDropDown.ValueIndex;
            db = app.SimulationH.Data;
            val = db(index,:);
            name = cellstr(val(1));
            V= cell2mat(val(2));
            alpha = cell2mat(val(4));
            g= cell2mat(val(5));
            tofl = (2 * V * sin(alpha)) / g;
            t = linspace(0, tofl, 100); % Time array
            x = V * cos(alpha) * t; % X - Axis
            y = V * sin(alpha) * t - 0.5 * g * t.^2; % Y - Axis
            app.hisname = [app.hisname; name];
            plot(app.Historygraph,x,y)
            disp(app.hisname)
            legend(app.Historygraph,app.hisname)
            hold(app.Historygraph,"on")


        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: simulate
        function simulateButtonPushed(app, event)
            input(app); % Input Function
            rtssenable(app); % RTSS Enable
            sim(app); % Simulation

            plotdisp(app);
            plotvel(app);
            plotacc(app);
        end

        % Button pushed function: resetall
        function resetallButtonPushed(app, event)
            reset(app); % Reset Function
        end

        % Button pushed function: locate
        function locateButtonPushed(app, event)
            locatew(app); % Locate function
        end

        % Button pushed function: ReplayButton
        function ReplayButtonPushed(app, event)
            if app.isAni
                pauseRTSS(app);
                app.cTime = 0;
                resumeRTSS(app);
                
            else
                startRTSS(app);
            end
        end

        % Button pushed function: FastRewindButton
        function FastRewindButtonPushed(app, event)
               tweakRTSS(app, -4);
        end

        % Button pushed function: Skipback
        function SkipbackPushed(app, event)
          if app.isAni
                pauseRTSS(app);
                app.cTime = 0;
                resumeRTSS(app);
            else
                startRTSS(app);
            end
        end

        % Value changed function: PlayPauseButton
        function PlayPauseButtonValueChanged(app, event)
            if app.isAni
                if app.isPlay
                    pauseRTSS(app);
                    app.timerr.Running
                else
                    resumeRTSS(app);
                    app.timerr.Running
                end
            end

        end

        % Button pushed function: Skipforward
        function SkipforwardPushed(app, event)
           if app.isAni
                pauseRTSS(app);
                app.cTime = app.TMAX;
                resumeRTSS(app);
            else
                startRTSS(app);
            end
        end

        % Button pushed function: FastForwardButton
        function FastForwardButtonPushed(app, event)
            tweakRTSS(app, 2);
        end

        % Value changed function: InputPlaybackSpeed
        function InputPlaybackSpeedValueChanged(app, event)
            app.pSpeed = app.InputPlaybackSpeed.Value;

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            s = inputdlg("Enter the name of the Data","Save");
            if isempty(s)
                disp("save canceled");
            else
                saveval(app,s)
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            importf(app);
            plothis(app);
        end

        % Button down function: HistoryTab
        function HistoryTabButtonDown(app, event)
            simh(app);
        end

        % Button down function: simulator
        function simulatorButtonDown(app, event)
            % Need to clear the drop down menu
            app.DataDropDown.Items = {};
            app.DataDropDown.ItemsData ={};
        end

        % Button down function: GraphsTab
        function GraphsTabButtonDown(app, event)
            app.DataDropDown.Items = {};
            app.DataDropDown.ItemsData ={};
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            hold(app.Historygraph,"off")
            app.hisname = [];
            plot(app.Historygraph,0,0)
            tabs = app.new_tab;
            for i  = tabs
                delete(i)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.simulator = uitab(app.TabGroup);
            app.simulator.Title = 'Simulator';
            app.simulator.ButtonDownFcn = createCallbackFcn(app, @simulatorButtonDown, true);
            app.simlayout = uigridlayout(app.simulator);
            app.MainGraph = uiaxes(app.simlayout);
            title(app.MainGraph, 'Projectile Motion')
            xlabel(app.MainGraph, 'Horizontal Distance (m)')
            ylabel(app.MainGraph, 'Vertical Distance (m)')
            zlabel(app.MainGraph, 'Z')
            app.RTSSPanel = uipanel(app.simlayout);
            app.RTSSPanel.Enable = 'off';
            app.RTSSPanel.Title = 'Real Time Simulator System ( RTSS )';
            app.InputPlaybackSpeed = uispinner(app.RTSSPanel);
            app.InputPlaybackSpeed.Step = 0.25;
            app.InputPlaybackSpeed.Limits = [0.25 2];
            app.InputPlaybackSpeed.ValueChangedFcn = createCallbackFcn(app, @InputPlaybackSpeedValueChanged, true);
            app.InputPlaybackSpeed.Enable = 'off';
            app.InputPlaybackSpeed.Tooltip = {'Set playback speed'};
            app.InputPlaybackSpeed.Value = 1;
            app.FastForwardButton = uibutton(app.RTSSPanel, 'push');
            app.FastForwardButton.ButtonPushedFcn = createCallbackFcn(app, @FastForwardButtonPushed, true);
            app.FastForwardButton.Icon = 'fast-forward.png';
            app.FastForwardButton.Enable = 'off';
            app.FastForwardButton.Text = '';
            app.Skipforward = uibutton(app.RTSSPanel, 'push');
            app.Skipforward.ButtonPushedFcn = createCallbackFcn(app, @SkipforwardPushed, true);
            app.Skipforward.Icon = 'Forward.png';
            app.Skipforward.Enable = 'off';
            app.Skipforward.Text = '';
            app.PlayPauseButton = uibutton(app.RTSSPanel, 'state');
            app.PlayPauseButton.ValueChangedFcn = createCallbackFcn(app, @PlayPauseButtonValueChanged, true);
            app.PlayPauseButton.Enable = 'off';
            app.PlayPauseButton.Icon = 'Play.png';
            app.PlayPauseButton.Text = '';
            app.Skipback = uibutton(app.RTSSPanel, 'push');
            app.Skipback.ButtonPushedFcn = createCallbackFcn(app, @SkipbackPushed, true);
            app.Skipback.Icon = 'Rewind.png';
            app.Skipback.Enable = 'off';
            app.Skipback.Text = '';
            app.FastRewindButton = uibutton(app.RTSSPanel, 'push');
            app.FastRewindButton.ButtonPushedFcn = createCallbackFcn(app, @FastRewindButtonPushed, true);
            app.FastRewindButton.Icon = 'FastRewind.png';
            app.FastRewindButton.Enable = 'off';
            app.FastRewindButton.Text = '';
            app.ReplayButton = uibutton(app.RTSSPanel, 'push');
            app.ReplayButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayButtonPushed, true);
            app.ReplayButton.Enable = 'off';
            app.ReplayButton.Text = 'Replay';
            app.DistanceRemainingValue = uilabel(app.RTSSPanel);
            app.DistanceRemainingValue.Text = '0.00 m';
            app.DistanceRemainingLabel = uilabel(app.RTSSPanel);
            app.DistanceRemainingLabel.Text = 'Distance Remaining:';
            app.DistanceCoveredValue = uilabel(app.RTSSPanel);
            app.DistanceCoveredValue.Text = '0.00 m';
            app.DistanceCoveredLabel = uilabel(app.RTSSPanel);
            app.DistanceCoveredLabel.Text = 'Distance Covered:';
            app.CurrentHeightValue = uilabel(app.RTSSPanel);
            app.CurrentHeightValue.Text = '0.00 m';
            app.CurrentHeightLabel = uilabel(app.RTSSPanel);
            app.CurrentHeightLabel.Text = 'Current Height:';
            app.TimeRemainingValue = uilabel(app.RTSSPanel);
            app.TimeRemainingValue.Text = '0.00 s';
            app.TimeRemainingLabel = uilabel(app.RTSSPanel);
            app.TimeRemainingLabel.Text = 'Time Remaining:';
            app.TimeElapsedValue = uilabel(app.RTSSPanel);
            app.TimeElapsedValue.Text = '0.00 s';
            app.TimeElapsedLabel = uilabel(app.RTSSPanel);
            app.TimeElapsedLabel.Text = 'Time Elapsed: ';
            app.InputPanel = uipanel(app.simlayout);
            app.InputPanel.Title = 'Input  ';
            app.acceleration = uidropdown(app.InputPanel);
            app.acceleration.Items = {'Earth', 'Moon', 'Mars'};
            app.acceleration.Value = 'Earth';
            app.mLabel = uilabel(app.InputPanel);
            app.mLabel.Text = 'm';
            app.AccelerationLabel = uilabel(app.InputPanel);
            app.AccelerationLabel.Text = 'Acceleration:';
            app.Hieght = uieditfield(app.InputPanel, 'numeric');
            app.IntialHeightLabel = uilabel(app.InputPanel);
            app.IntialHeightLabel.Text = 'Height of building: ';
            app.RangeLabel = uilabel(app.InputPanel);
            app.RangeLabel.Text = 'Distance from building: ';
            app.Distance = uieditfield(app.InputPanel, 'numeric');
            app.mLabel_3 = uilabel(app.InputPanel);
            app.mLabel_3.Text = 'm';
            app.RTSSSwitch = uiswitch(app.InputPanel, 'slider');
            app.RTSSSwitchLabel = uilabel(app.InputPanel);
            app.RTSSSwitchLabel.Tooltip = {'Real Time Simulator System'};
            app.RTSSSwitchLabel.Text = 'RTSS';
            app.simulate = uibutton(app.InputPanel, 'push');
            app.simulate.ButtonPushedFcn = createCallbackFcn(app, @simulateButtonPushed, true);
            app.simulate.Text = 'Simulate';
            app.resetall = uibutton(app.InputPanel, 'push');
            app.resetall.ButtonPushedFcn = createCallbackFcn(app, @resetallButtonPushed, true);
            app.resetall.Text = 'Reset All';
            app.SaveButton = uibutton(app.InputPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = ' 💾 Save';
            app.OutputPanel = uipanel(app.simlayout);
            app.OutputPanel.Title = 'Output';
            app.MaxHieghtLabel = uilabel(app.OutputPanel);
            app.MaxHieghtLabel.Text = 'Max Height:';
            app.MH = uieditfield(app.OutputPanel, 'numeric');
            app.MH.Editable = 'off';
            app.mLabel_2 = uilabel(app.OutputPanel);
            app.mLabel_2.Text = 'm';
            app.VelocityLabel = uilabel(app.OutputPanel);
            app.VelocityLabel.Text = 'Velocity: ';
            app.velocity = uieditfield(app.OutputPanel, 'numeric');
            app.velocity.Editable = 'off';
            app.radsLabel = uilabel(app.OutputPanel);
            app.radsLabel.Text = 'rads';
            app.AngleofLaunchLabel = uilabel(app.OutputPanel);
            app.AngleofLaunchLabel.Text = 'Angle:';
            app.angle = uieditfield(app.OutputPanel, 'numeric');
            app.angle.Editable = 'off';
            app.msLabel = uilabel(app.OutputPanel);
            app.msLabel.Text = 'm/s';
            app.secLabel_2 = uilabel(app.OutputPanel);
            app.secLabel_2.Text = 'sec';
            app.TimeofflightLabel = uilabel(app.OutputPanel);
            app.TimeofflightLabel.Text = 'Time of flight:';
            app.timeofflight = uieditfield(app.OutputPanel, 'numeric');
            app.timeofflight.Editable = 'off';
            app.mLabel_4 = uilabel(app.OutputPanel);
            app.mLabel_4.Text = 'm';
            app.PeakLocationEditFieldLabel = uilabel(app.OutputPanel);
            app.PeakLocationEditFieldLabel.Text = 'Peak Location:';
            app.peak = uieditfield(app.OutputPanel, 'text');
            app.peak.Editable = 'off';
            app.peak.Placeholder = '(x,y)';
            app.LocationTrackerPanel = uipanel(app.simlayout);
            app.LocationTrackerPanel.Title = 'Location Tracker';
            app.EnterthetimetocheckthelocationoftheobjectEditFieldLabel = uilabel(app.LocationTrackerPanel);
            app.EnterthetimetocheckthelocationoftheobjectEditFieldLabel.Text = 'Enter the time to check the location of the object: ';
            app.timelocate = uieditfield(app.LocationTrackerPanel, 'numeric');
            app.timelocate.Editable = 'off';
            app.timelocate.Enable = 'off';
            app.locationdisp = uitextarea(app.LocationTrackerPanel);
            app.locationdisp.Editable = 'off';
            app.locationdisp.Placeholder = 'At time t s, the object is at position x, y';
            app.secLabel = uilabel(app.LocationTrackerPanel);
            app.secLabel.Enable = 'off';
            app.secLabel.Text = 'sec';
            app.locate = uibutton(app.LocationTrackerPanel, 'push');
            app.locate.ButtonPushedFcn = createCallbackFcn(app, @locateButtonPushed, true);
            app.locate.Enable = 'off';
            app.locate.Text = 'Locate';
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.GraphsTab.ButtonDownFcn = createCallbackFcn(app, @GraphsTabButtonDown, true);
            app.GridLayout = uigridlayout(app.GraphsTab);
            app.AccelerationTimeGraph = uiaxes(app.GridLayout);
            title(app.AccelerationTimeGraph, 'Acceleration - Time')
            xlabel(app.AccelerationTimeGraph, 'Time (s)')
            ylabel(app.AccelerationTimeGraph, 'Acceleration (m/s²)')
            zlabel(app.AccelerationTimeGraph, 'Z')
            app.VelocityTimeGraph = uiaxes(app.GridLayout);
            title(app.VelocityTimeGraph, 'Velocity - Time')
            xlabel(app.VelocityTimeGraph, 'Time (s)')
            ylabel(app.VelocityTimeGraph, 'Velocity (m/s)')
            zlabel(app.VelocityTimeGraph, 'Z')
            app.DisplacementTimeGraph = uiaxes(app.GridLayout);
            title(app.DisplacementTimeGraph, 'Displacement - Time')
            xlabel(app.DisplacementTimeGraph, 'Time (s)')
            ylabel(app.DisplacementTimeGraph, 'Displacement (m)')
            zlabel(app.DisplacementTimeGraph, 'Z')
            app.MaxDisplacementLabel = uilabel(app.GridLayout);
            app.MaxDisplacementLabel.Text = 'Maximum Displacement';
            app.MaxDisplacement = uieditfield(app.GridLayout, 'numeric');
            app.MaxDisplacement.Editable = 'off';
            app.MaxDisplacementLabel_2 = uilabel(app.GridLayout);
            app.MaxDisplacementLabel_2.Text = 'Minimum Displacement';
            app.MinDisplacement = uieditfield(app.GridLayout, 'numeric');
            app.MinDisplacement.Editable = 'off';
            app.MaxDisplacementLabel_3 = uilabel(app.GridLayout);
            app.MaxDisplacementLabel_3.Text = 'Average Velocity (m/s)';
            app.AvgVelocity = uieditfield(app.GridLayout, 'numeric');
            app.AvgVelocity.Editable = 'off';
            app.MaxDisplacementLabel_4 = uilabel(app.GridLayout);
            app.MaxDisplacementLabel_4.Text = 'Maximum Velocity';
            app.MaxVelocity = uieditfield(app.GridLayout, 'numeric');
            app.MaxVelocity.Editable = 'off';
            app.MaxDisplacementLabel_5 = uilabel(app.GridLayout);
            app.MaxDisplacementLabel_5.Text = 'Minimum Velocity';
            app.MinVelocity = uieditfield(app.GridLayout, 'numeric');
            app.MinVelocity.Editable = 'off';
            app.MaxDisplacementLabel_7 = uilabel(app.GridLayout);
            app.MaxDisplacementLabel_7.Text = 'Avg Acceleration (m/s²)';
            app.AvgAcceleration = uieditfield(app.GridLayout, 'numeric');
            app.AvgAcceleration.Editable = 'off';
            app.MaxDisplacementLabel_8 = uilabel(app.GridLayout);
            app.MaxDisplacementLabel_8.Text = 'Maximum Acceleration';
            app.MaxAcceleration = uieditfield(app.GridLayout, 'numeric');
            app.MaxAcceleration.Editable = 'off';
            app.MaxDisplacementLabel_9 = uilabel(app.GridLayout);
            app.MaxDisplacementLabel_9.Text = 'Minimum Acceleration';
            app.MinAcceleration = uieditfield(app.GridLayout, 'numeric');
            app.MinAcceleration.Editable = 'off';
            app.MaxDisplacementLabel_10 = uilabel(app.GridLayout);
            app.MaxDisplacementLabel_10.Text = 'Jerk (m/s³)';
            app.Jerk = uieditfield(app.GridLayout, 'numeric');
            app.Jerk.Editable = 'off';
            app.HistoryTab = uitab(app.TabGroup);
            app.HistoryTab.Title = 'History';
            app.HistoryTab.ButtonDownFcn = createCallbackFcn(app, @HistoryTabButtonDown, true);
            app.GridLayout2 = uigridlayout(app.HistoryTab);
            app.Historygraph = uiaxes(app.GridLayout2);
            title(app.Historygraph, 'History Graph')
            xlabel(app.Historygraph, 'Horizontal Distance (m)')
            ylabel(app.Historygraph, 'Vertical Distance (m)')
            zlabel(app.Historygraph, 'Z')
            app.SimulationH = uitable(app.GridLayout2);
            app.SimulationH.ColumnName = {'Name'; 'Velocity'; 'Intial Hieght'; 'Angle of Launch (rads)'; 'Acceleration'};
            app.SimulationH.RowName = {};
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.MainTab = uitab(app.TabGroup2);
            app.MainTab.Title = 'Main';
            app.ImportButton = uibutton(app.MainTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.DataDropDownLabel = uilabel(app.MainTab);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.MainTab);
            app.DataDropDown.Items = {};
            app.DataDropDown.Value = {};
            app.ClearAllButton = uibutton(app.MainTab, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.ProjectileSimulatorLabel = uilabel(app.UIFigure);
            app.ProjectileSimulatorLabel.Text = 'Projectile Simulator';
            app.Versionlabel = uilabel(app.UIFigure);
            app.Versionlabel.Text = 'V3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Projectile_Simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end